classdef ConfigCSVGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        event_mapEditFieldLabel         matlab.ui.control.Label
        is_non_strobed_and_strobedDropDown  matlab.ui.control.DropDown
        event_mapEditField              matlab.ui.control.EditField
        trial_rangeEditFieldLabel       matlab.ui.control.Label
        trial_rangeEditField            matlab.ui.control.EditField
        wanted_eventsEditFieldLabel     matlab.ui.control.Label
        wanted_eventsEditField          matlab.ui.control.EditField
        NAEditField_2Label              matlab.ui.control.Label
        NAEditField_2                   matlab.ui.control.EditField
        NAEditField_3Label              matlab.ui.control.Label
        NAEditField_3                   matlab.ui.control.EditField
        bin_sizeEditFieldLabel          matlab.ui.control.Label
        bin_sizeEditField               matlab.ui.control.NumericEditField
        pre_timeEditFieldLabel          matlab.ui.control.Label
        pre_timeEditField               matlab.ui.control.NumericEditField
        post_timeEditFieldLabel         matlab.ui.control.Label
        post_timeEditField              matlab.ui.control.NumericEditField
        total_trialsEditFieldLabel      matlab.ui.control.Label
        total_trialsEditField           matlab.ui.control.NumericEditField
        trial_lower_boundEditFieldLabel  matlab.ui.control.Label
        trial_lower_boundEditField      matlab.ui.control.NumericEditField
        total_eventsEditFieldLabel      matlab.ui.control.Label
        total_eventsEditField           matlab.ui.control.NumericEditField
        boot_iterationsEditFieldLabel   matlab.ui.control.Label
        boot_iterationsEditField        matlab.ui.control.NumericEditField
        norm_var_scalingEditFieldLabel  matlab.ui.control.Label
        norm_var_scalingEditField       matlab.ui.control.NumericEditField
        epsilonEditFieldLabel           matlab.ui.control.Label
        epsilonEditField                matlab.ui.control.NumericEditField
        sub_columnsEditFieldLabel       matlab.ui.control.Label
        sub_columnsEditField            matlab.ui.control.NumericEditField
        sig_checkEditFieldLabel         matlab.ui.control.Label
        sig_checkEditField              matlab.ui.control.NumericEditField
        sig_binsEditFieldLabel          matlab.ui.control.Label
        sig_binsEditField               matlab.ui.control.NumericEditField
        threshold_scaleEditFieldLabel   matlab.ui.control.Label
        threshold_scaleEditField        matlab.ui.control.NumericEditField
        spanEditFieldLabel              matlab.ui.control.Label
        spanEditField                   matlab.ui.control.NumericEditField
        parse_filesDropDownLabel        matlab.ui.control.Label
        parse_filesDropDown             matlab.ui.control.DropDown
        label_channelsDropDownLabel     matlab.ui.control.Label
        label_channelsDropDown          matlab.ui.control.DropDown
        create_psthDropDownLabel        matlab.ui.control.Label
        create_psthDropDown             matlab.ui.control.DropDown
        rf_analysisDropDownLabel        matlab.ui.control.Label
        rf_analysisDropDown             matlab.ui.control.DropDown
        make_psth_graphsDropDownLabel   matlab.ui.control.Label
        make_psth_graphsDropDown        matlab.ui.control.DropDown
        nv_analysisDropDownLabel        matlab.ui.control.Label
        nv_analysisDropDown             matlab.ui.control.DropDown
        info_analysisDropDownLabel      matlab.ui.control.Label
        info_analysisDropDown           matlab.ui.control.DropDown
        psth_classifyDropDownLabel      matlab.ui.control.Label
        psth_classifyDropDown           matlab.ui.control.DropDown
        ignore_animalDropDownLabel      matlab.ui.control.Label
        ignore_animalDropDown           matlab.ui.control.DropDown
        make_region_subplotDropDownLabel  matlab.ui.control.Label
        make_region_subplotDropDown     matlab.ui.control.DropDown
        separate_eventsDropDownLabel    matlab.ui.control.Label
        separate_eventsDropDown         matlab.ui.control.DropDown
        bootstrap_classifierDropDownLabel  matlab.ui.control.Label
        bootstrap_classifierDropDown    matlab.ui.control.DropDown
        CompileButton                   matlab.ui.control.Button
        DefaultButton                   matlab.ui.control.Button
        is_non_strobed_and_strobedDropDownLabel  matlab.ui.control.Label
        NameofConfigFileEditFieldLabel  matlab.ui.control.Label
        NameofConfigFileEditField       matlab.ui.control.EditField
        PathEditFieldLabel              matlab.ui.control.Label
        PathEditField                   matlab.ui.control.EditField
        WantCopiesCheckBox              matlab.ui.control.CheckBox
        ofCopiesEditFieldLabel          matlab.ui.control.Label
        ofCopiesEditField               matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function mycallback(app,src,event)
            if(strcmp(event.SelectedOption,'Cancel'))
            elseif (strcmp(event.SelectedOption,'OK'))
                app.parse_filesDropDown.Value = 'true';
                app.label_channelsDropDown.Value = 'true';
                app.create_psthDropDown.Value = 'true';
                app.rf_analysisDropDown.Value = 'true';
                app.make_psth_graphsDropDown.Value = 'true';
                app.nv_analysisDropDown.Value = 'true';
                app.info_analysisDropDown.Value = 'true';
                app.psth_classifyDropDown.Value = 'true';
                app.bin_sizeEditField.Value = 0.005;
                app.pre_timeEditField.Value = 0.1;
                app.post_timeEditField.Value = 0.2;
                app.ignore_animalDropDown.Value = 'true';
                app.total_trialsEditField.Value = 100;
                app.trial_lower_boundEditField.Value = 50;
                app.total_eventsEditField.Value = 4;            
                app.is_non_strobed_and_strobedDropDown.Value = 'true'; 
                app.event_mapEditField.Value = '';
                app.trial_rangeEditField.Value = '';
                app.wanted_eventsEditField.Value = '';
                app.spanEditField.Value = 3;
                app.threshold_scaleEditField.Value = 1.65;
                app.sig_binsEditField.Value = 3;
                app.sig_checkEditField.Value = 1;
                app.make_region_subplotDropDown.Value = 'true';
                app.sub_columnsEditField.Value = 50;
                app.epsilonEditField.Value = 0.01;
                app.norm_var_scalingEditField.Value = 0.2;
                app.separate_eventsDropDown.Value = 'true';
                app.bootstrap_classifierDropDown.Value = 'true';
                app.boot_iterationsEditField.Value = 50;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CompileButton
        function CompileButtonPushed(app, event)
            parse_files = app.parse_filesDropDown.Value;
            label_channels = app.label_channelsDropDown.Value;
            create_psth = app.create_psthDropDown.Value;
            rf_analysis = app.rf_analysisDropDown.Value;
            make_psth_graphs = app.make_psth_graphsDropDown.Value;
            nv_analysis = app.nv_analysisDropDown.Value;
            info_analysis = app.info_analysisDropDown.Value;
            psth_classify = app.psth_classifyDropDown.Value;
            bin_size = app.bin_sizeEditField.Value;
            pre_time = app.pre_timeEditField.Value;
            post_time = app.post_timeEditField.Value;
            ignore_animal = app.ignore_animalDropDown.Value;
            total_trials = app.total_trialsEditField.Value;
            trial_lower_bound = app.trial_lower_boundEditField.Value;
            total_events = app.total_eventsEditField.Value;
            is_non_strobed_and_strobed = app.is_non_strobed_and_strobedDropDown.Value;
            event_map = app.event_mapEditField.Value;
            trial_range = app.trial_rangeEditField.Value;
            wanted_events = app.wanted_eventsEditField.Value;
            span = app.spanEditField.Value;
            threshold_scale = app.threshold_scaleEditField.Value;
            sig_bins = app.sig_binsEditField.Value;
            sig_check = app.sig_checkEditField.Value;
            make_region_subplot = app.make_region_subplotDropDown.Value;
            sub_columns = app.sub_columnsEditField.Value;
            epsilon = app.epsilonEditField.Value;
            norm_var_scaling = app.norm_var_scalingEditField.Value;
            separate_events = app.separate_eventsDropDown.Value;
            bootstrap_classifier = app.bootstrap_classifierDropDown.Value;
            boot_iterations = app.boot_iterationsEditField.Value;
            configFileName = app.NameofConfigFileEditField.Value;
            pathOfFile = app.PathEditField.Value;
            copyBoolean = app.WantCopiesCheckBox.Value;
            numberOfLoops = app.ofCopiesEditField.Value;
            
            display(copyBoolean);
            
            loops = 1;
            
            if(copyBoolean == 1)
                loops = numberOfLoops;
            end
            
            %this is for fields with array of numerical values
            if event_map ~= ""
                event_map = erase(event_map,{'[',']'});
                event_map = strrep(event_map,' ',',');
            end
            
            if trial_range ~= ""
                trial_range = erase(trial_range,{'[',']'});
                trial_range = strrep(trial_range,' ',',');
            end
            
            if wanted_events ~= ""
                wanted_events = erase(wanted_events,{'[',']'});
                wanted_events = strrep(wanted_events,' ',',');
            end
            
            % Put variable names in here
            variables = ["parse_files" "label_channels" "create_psth" "rf_analysis" "make_psth_graphs" "nv_analysis" "info_analysis" ...
                "psth_classify" "bin_size" "pre_time" "post_time" "ignore_animal" "total_trials" "trial_lower_bound" ...
                "total_events" "is_non_strobed_and_strobed" "event_map" "trial_range" "wanted_events" "span" "threshold_scale" ...
                "sig_bins" "sig_check" "make_region_subplot" "sub_columns" "epsilon" "norm_var_scaling" "separate_events" ...
                "bootstrap_classifier" "boot_iterations"];
            
            disp(parse_files)
            % Put variable values in here
            values = [string(parse_files),label_channels,create_psth,rf_analysis,make_psth_graphs,nv_analysis,info_analysis,psth_classify ...
                ,bin_size,pre_time,post_time,ignore_animal,total_trials,trial_lower_bound,total_events,is_non_strobed_and_strobed ...
                ,event_map,trial_range,wanted_events,span,threshold_scale,sig_bins,sig_check,make_region_subplot,sub_columns ...
                ,epsilon,norm_var_scaling,separate_events,bootstrap_classifier,boot_iterations];
            
            T = array2table(values','RowNames',variables');
            
            if(strcmp(string(configFileName),''))
                configFileName = 'config';
            end
            
            for c = 1:loops
                if(c == 1)
                    nameForCSV = strcat(string(pathOfFile),string(configFileName),'.csv');
                else
                    numberString = strcat('_',string(c-1));
                    nameForCSV = strcat(string(pathOfFile),string(configFileName),numberString,'.csv');
                end
                writetable(T,string(nameForCSV),'WriteRowNames',true,'WriteVariableNames',0)
            end
            f = msgbox('Generated CSV File');
            waitfor(f);
            app.delete;
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            uiconfirm(app.UIFigure,'Restore default settings?','Confirm Close',...
                        'CloseFcn',@(src,event)mycallback(app,src,event));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.event_mapEditFieldLabel = uilabel(app.UIFigure);
            app.event_mapEditFieldLabel.Text = 'event_map';
            app.is_non_strobed_and_strobedDropDown = uidropdown(app.UIFigure);
            app.is_non_strobed_and_strobedDropDown.Items = {'true', 'false'};
            app.is_non_strobed_and_strobedDropDown.Tooltip = {'Recordings is a mix of strobed and non strobed'};
            app.is_non_strobed_and_strobedDropDown.Value = 'true';
            app.event_mapEditField = uieditfield(app.UIFigure, 'text');
            app.event_mapEditField.Tooltip = {'Maps strobed to non strobed. Can be left empty.'; ''; 'ex. [1 2 3 6]'};
            app.trial_rangeEditFieldLabel = uilabel(app.UIFigure);
            app.trial_rangeEditFieldLabel.Text = 'trial_range';
            app.trial_rangeEditField = uieditfield(app.UIFigure, 'text');
            app.trial_rangeEditField.Tooltip = {'Inclusive range for trials. When empty'; ' takes all trials.'; ''; 'ex. [1 300]'};
            app.wanted_eventsEditFieldLabel = uilabel(app.UIFigure);
            app.wanted_eventsEditFieldLabel.Text = 'wanted_events';
            app.wanted_eventsEditField = uieditfield(app.UIFigure, 'text');
            app.wanted_eventsEditField.Tooltip = {'Controls which events are used. If empty'; ' uses all events.'; ''; 'ex. [1 3 4]'};
            app.NAEditField_2Label = uilabel(app.UIFigure);
            app.NAEditField_2Label.Text = 'N/A';
            app.NAEditField_2 = uieditfield(app.UIFigure, 'text');
            app.NAEditField_3Label = uilabel(app.UIFigure);
            app.NAEditField_3Label.Text = 'N/A';
            app.NAEditField_3 = uieditfield(app.UIFigure, 'text');
            app.is_non_strobed_and_strobedDropDownLabel = uilabel(app.UIFigure);
            app.is_non_strobed_and_strobedDropDownLabel.Text = 'is_non_strobed_and_strobed';
            app.bin_sizeEditFieldLabel = uilabel(app.UIFigure);
            app.bin_sizeEditFieldLabel.Text = 'bin_size';
            app.bin_sizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.bin_sizeEditField.Tooltip = {'Bin size to make histogram'};
            app.bin_sizeEditField.Value = 0.005;
            app.pre_timeEditFieldLabel = uilabel(app.UIFigure);
            app.pre_timeEditFieldLabel.Text = 'pre_time';
            app.pre_timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.pre_timeEditField.Tooltip = {'Pre time window before event onset'};
            app.pre_timeEditField.Value = 0.1;
            app.post_timeEditFieldLabel = uilabel(app.UIFigure);
            app.post_timeEditFieldLabel.Text = 'post_time';
            app.post_timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.post_timeEditField.Tooltip = {'Post time window after event onset'};
            app.post_timeEditField.Value = 0.2;
            app.total_trialsEditFieldLabel = uilabel(app.UIFigure);
            app.total_trialsEditFieldLabel.Text = 'total_trials';
            app.total_trialsEditField = uieditfield(app.UIFigure, 'numeric');
            app.total_trialsEditField.Tooltip = {'Total trials for each event'};
            app.total_trialsEditField.Value = 100;
            app.trial_lower_boundEditFieldLabel = uilabel(app.UIFigure);
            app.trial_lower_boundEditFieldLabel.Text = 'trial_lower_bound';
            app.trial_lower_boundEditField = uieditfield(app.UIFigure, 'numeric');
            app.trial_lower_boundEditField.Tooltip = {'Lower limit of expected trials for each event'};
            app.trial_lower_boundEditField.Value = 50;
            app.total_eventsEditFieldLabel = uilabel(app.UIFigure);
            app.total_eventsEditFieldLabel.Text = 'total_events';
            app.total_eventsEditField = uieditfield(app.UIFigure, 'numeric');
            app.total_eventsEditField.Tooltip = {'How many events are there'};
            app.total_eventsEditField.Value = 4;
            app.boot_iterationsEditFieldLabel = uilabel(app.UIFigure);
            app.boot_iterationsEditFieldLabel.Text = 'boot_iterations';
            app.boot_iterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.boot_iterationsEditField.Tooltip = {'How many iterations used to find avg random info'};
            app.boot_iterationsEditField.Value = 50;
            app.norm_var_scalingEditFieldLabel = uilabel(app.UIFigure);
            app.norm_var_scalingEditFieldLabel.Text = 'norm_var_scaling';
            app.norm_var_scalingEditField = uieditfield(app.UIFigure, 'numeric');
            app.norm_var_scalingEditField.Tooltip = {'Scale the normalized variance'};
            app.norm_var_scalingEditField.Value = 0.2;
            app.epsilonEditFieldLabel = uilabel(app.UIFigure);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.epsilonEditField.Tooltip = {'Constant used to prevent division by zero'};
            app.epsilonEditField.Value = 0.01;
            app.sub_columnsEditFieldLabel = uilabel(app.UIFigure);
            app.sub_columnsEditFieldLabel.Text = 'sub_columns';
            app.sub_columnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.sub_columnsEditField.Tooltip = {'How many PSTHs plotted on row.'};
            app.sub_columnsEditField.Value = 50;
            app.sig_checkEditFieldLabel = uilabel(app.UIFigure);
            app.sig_checkEditFieldLabel.Text = 'sig_check';
            app.sig_checkEditField = uieditfield(app.UIFigure, 'numeric');
            app.sig_checkEditField.Tooltip = {'Control statistical check for significant response'};
            app.sig_checkEditField.Value = 1;
            app.sig_binsEditFieldLabel = uilabel(app.UIFigure);
            app.sig_binsEditFieldLabel.Text = 'sig_bins';
            app.sig_binsEditField = uieditfield(app.UIFigure, 'numeric');
            app.sig_binsEditField.Tooltip = {'# consecutive bins needed above threshold for significance'};
            app.sig_binsEditField.Value = 3;
            app.threshold_scaleEditFieldLabel = uilabel(app.UIFigure);
            app.threshold_scaleEditFieldLabel.Text = 'threshold_scale';
            app.threshold_scaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.threshold_scaleEditField.Tooltip = {'Scales background firing rate standard deviation for threshold'};
            app.threshold_scaleEditField.Value = 1.65;
            app.spanEditFieldLabel = uilabel(app.UIFigure);
            app.spanEditFieldLabel.Text = 'span';
            app.spanEditField = uieditfield(app.UIFigure, 'numeric');
            app.spanEditField.Tooltip = {'How many bins smoothed when finding significant response'};
            app.spanEditField.Value = 3;
            app.parse_filesDropDownLabel = uilabel(app.UIFigure);
            app.parse_filesDropDownLabel.Text = 'parse_files';
            app.parse_filesDropDown = uidropdown(app.UIFigure);
            app.parse_filesDropDown.Items = {'true', 'false'};
            app.parse_filesDropDown.Tooltip = {'Controls if .plx/.pl2 files are parsed'};
            app.parse_filesDropDown.Value = 'true';
            app.label_channelsDropDownLabel = uilabel(app.UIFigure);
            app.label_channelsDropDownLabel.Text = 'label_channels';
            app.label_channelsDropDown = uidropdown(app.UIFigure);
            app.label_channelsDropDown.Items = {'true', 'false'};
            app.label_channelsDropDown.Tooltip = {'Label neurons according to labels csv'};
            app.label_channelsDropDown.Value = 'true';
            app.create_psthDropDownLabel = uilabel(app.UIFigure);
            app.create_psthDropDownLabel.Text = 'create_psth';
            app.create_psthDropDown = uidropdown(app.UIFigure);
            app.create_psthDropDown.Items = {'true', 'false'};
            app.create_psthDropDown.Tooltip = {'Format PSTH from parsed data'};
            app.create_psthDropDown.Value = 'true';
            app.rf_analysisDropDownLabel = uilabel(app.UIFigure);
            app.rf_analysisDropDownLabel.Text = 'rf_analysis';
            app.rf_analysisDropDown = uidropdown(app.UIFigure);
            app.rf_analysisDropDown.Items = {'true', 'false'};
            app.rf_analysisDropDown.Tooltip = {'Run receptive field analysis'};
            app.rf_analysisDropDown.Value = 'true';
            app.make_psth_graphsDropDownLabel = uilabel(app.UIFigure);
            app.make_psth_graphsDropDownLabel.Text = 'make_psth_graphs';
            app.make_psth_graphsDropDown = uidropdown(app.UIFigure);
            app.make_psth_graphsDropDown.Items = {'true', 'false'};
            app.make_psth_graphsDropDown.Tooltip = {'Create PSTH graphs'};
            app.make_psth_graphsDropDown.Value = 'true';
            app.nv_analysisDropDownLabel = uilabel(app.UIFigure);
            app.nv_analysisDropDownLabel.Text = 'nv_analysis';
            app.nv_analysisDropDown = uidropdown(app.UIFigure);
            app.nv_analysisDropDown.Items = {'true', 'false'};
            app.nv_analysisDropDown.Tooltip = {'Run normalized variance analysis'};
            app.nv_analysisDropDown.Value = 'true';
            app.info_analysisDropDownLabel = uilabel(app.UIFigure);
            app.info_analysisDropDownLabel.Text = 'info_analysis';
            app.info_analysisDropDown = uidropdown(app.UIFigure);
            app.info_analysisDropDown.Items = {'true', 'false'};
            app.info_analysisDropDown.Tooltip = {'Run mutual info analysis'};
            app.info_analysisDropDown.Value = 'true';
            app.psth_classifyDropDownLabel = uilabel(app.UIFigure);
            app.psth_classifyDropDownLabel.Text = 'psth_classify';
            app.psth_classifyDropDown = uidropdown(app.UIFigure);
            app.psth_classifyDropDown.Items = {'true', 'false'};
            app.psth_classifyDropDown.Tooltip = {'Run psth classification'};
            app.psth_classifyDropDown.Value = 'true';
            app.ignore_animalDropDownLabel = uilabel(app.UIFigure);
            app.ignore_animalDropDownLabel.Text = 'ignore_animal';
            app.ignore_animalDropDown = uidropdown(app.UIFigure);
            app.ignore_animalDropDown.Items = {'true', 'false'};
            app.ignore_animalDropDown.Tooltip = {'Skip animal'};
            app.ignore_animalDropDown.Value = 'true';
            app.NameofConfigFileEditFieldLabel = uilabel(app.UIFigure);
            app.NameofConfigFileEditFieldLabel.Text = 'Name of Config File';
            app.NameofConfigFileEditField = uieditfield(app.UIFigure, 'text');
            app.make_region_subplotDropDownLabel = uilabel(app.UIFigure);
            app.make_region_subplotDropDownLabel.Text = 'make_region_subplot';
            app.make_region_subplotDropDown = uidropdown(app.UIFigure);
            app.make_region_subplotDropDown.Items = {'true', 'false'};
            app.make_region_subplotDropDown.Tooltip = {'Plot all units on single .fig'};
            app.make_region_subplotDropDown.Value = 'true';
            app.separate_eventsDropDownLabel = uilabel(app.UIFigure);
            app.separate_eventsDropDownLabel.Text = 'separate_events';
            app.separate_eventsDropDown = uidropdown(app.UIFigure);
            app.separate_eventsDropDown.Items = {'true', 'false'};
            app.separate_eventsDropDown.Tooltip = {'Separate event trials or combine all trials'};
            app.separate_eventsDropDown.Value = 'true';
            app.bootstrap_classifierDropDownLabel = uilabel(app.UIFigure);
            app.bootstrap_classifierDropDownLabel.Text = 'bootstrap_classifier';
            app.bootstrap_classifierDropDown = uidropdown(app.UIFigure);
            app.bootstrap_classifierDropDown.Items = {'true', 'false'};
            app.bootstrap_classifierDropDown.Tooltip = {'Find random info via bootstrapping'};
            app.bootstrap_classifierDropDown.Value = 'true';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditField.Tooltip = {'Starting from directory where application is located.'; ''; 'ex. testing_directory/'};
            app.WantCopiesCheckBox = uicheckbox(app.UIFigure);
            app.WantCopiesCheckBox.Text = 'Want Copies?';
            app.ofCopiesEditFieldLabel = uilabel(app.UIFigure);
            app.ofCopiesEditFieldLabel.Text = '# of Copies';
            app.ofCopiesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofCopiesEditField.Value = 1;
            app.CompileButton = uibutton(app.UIFigure, 'push');
            app.CompileButton.ButtonPushedFcn = createCallbackFcn(app, @CompileButtonPushed, true);
            app.CompileButton.Text = 'Compile';
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Tooltip = {'restore values to default values'};
            app.DefaultButton.Text = 'Default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigCSVGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end